classdef nCORTEx_console < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DiscardButton                   matlab.ui.control.Button
        SessionLabelLabel               matlab.ui.control.Label
        LabelButton                     matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        ExpmntDropDown                  matlab.ui.control.DropDown
        Switch2                         matlab.ui.control.Switch
        StatusBar                       slrealtime.ui.control.StatusBar
        SelectSubjectDropDown           matlab.ui.control.DropDown
        SessionTimerminEditField        matlab.ui.control.NumericEditField
        connectTarget                   matlab.ui.control.StateButton
        LoggingLabel                    matlab.ui.control.Label
        SelectProjectDirectoryDropDown  matlab.ui.control.DropDown
        DataBufferButton                matlab.ui.control.Button
        DataStreamingModalitiesLabel    matlab.ui.control.Label
        PiezoPanel                      matlab.ui.container.Panel
        PiezoSwitch                     matlab.ui.control.Switch
        Switch2Label                    matlab.ui.control.Label
        OptogeneticsPanel               matlab.ui.container.Panel
        LRLabel_7                       matlab.ui.control.Label
        OptoLRSwitch                    matlab.ui.control.Switch
        OptoWavelengthDropDown          matlab.ui.control.DropDown
        WavelengthDropDown_6Label       matlab.ui.control.Label
        WavelengthnmLabel               matlab.ui.control.Label
        StimPatternLabel_2              matlab.ui.control.Label
        OpsinLabel                      matlab.ui.control.Label
        OptoStimPatternDropDown         matlab.ui.control.DropDown
        StimPatternDropDownLabel        matlab.ui.control.Label
        OpsinDropDown                   matlab.ui.control.DropDown
        OpsinDropDownLabel              matlab.ui.control.Label
        FiberLocationLabel_2            matlab.ui.control.Label
        OptoFiber1LocDropDown           matlab.ui.control.DropDown
        Fiber10Label                    matlab.ui.control.Label
        OptogeneticsSwitch              matlab.ui.control.Switch
        SessionMetricsPanel             matlab.ui.container.Panel
        StdHzLabel                      matlab.ui.control.Label
        MeanHzLabel                     matlab.ui.control.Label
        NpxlsStatLabel                  matlab.ui.control.Label
        WhiskStatLabel                  matlab.ui.control.Label
        PupilStatLabel                  matlab.ui.control.Label
        NpxlsStdHz                      matlab.ui.control.Label
        WhiskStdHz                      matlab.ui.control.Label
        PupilStdHz                      matlab.ui.control.Label
        NpxlsMeanHz                     matlab.ui.control.Label
        WhiskMeanHz                     matlab.ui.control.Label
        PupilMeanHz                     matlab.ui.control.Label
        HitRateLabel                    matlab.ui.control.Label
        HitRate                         matlab.ui.control.Label
        ValidateButton                  matlab.ui.control.Button
        PupilFrmCount                   matlab.ui.control.Label
        WhiskFrmCount                   matlab.ui.control.Label
        WhiskLabel                      matlab.ui.control.Label
        PupilLabel                      matlab.ui.control.Label
        UIAxes_WhiskFrames              matlab.ui.control.UIAxes
        UIAxes_PupilFrames              matlab.ui.control.UIAxes
        SelectSubjectDropDownLabel      matlab.ui.control.Label
        SessionTimerminEditFieldLabel   matlab.ui.control.Label
        VisionPanel                     matlab.ui.container.Panel
        SpinviewButton                  matlab.ui.control.Button
        WhiskerSNLabel                  matlab.ui.control.Label
        PupilSNLabel                    matlab.ui.control.Label
        WhiskFSEditField                matlab.ui.control.NumericEditField
        WhiskFSEditFieldLabel           matlab.ui.control.Label
        WhiskerSNDropDown               matlab.ui.control.DropDown
        WhiskerSNLabel_2                matlab.ui.control.Label
        PupilFSEditField                matlab.ui.control.NumericEditField
        PupilFSEditField_2Label         matlab.ui.control.Label
        VisionSwitch                    matlab.ui.control.Switch
        PupilSNDropDown                 matlab.ui.control.DropDown
        PupilSNLabel_2                  matlab.ui.control.Label
        PharmacologyPanel               matlab.ui.container.Panel
        PharmaLRLabel                   matlab.ui.control.Label
        DosagemLLabel                   matlab.ui.control.Label
        DrugLabel                       matlab.ui.control.Label
        ReceptorLabel                   matlab.ui.control.Label
        PharmaLRSwitch                  matlab.ui.control.Switch
        DosageDropDown                  matlab.ui.control.DropDown
        DosageDropDown_2Label           matlab.ui.control.Label
        InjectionLocationLabel          matlab.ui.control.Label
        InjLocDropDown                  matlab.ui.control.DropDown
        InjLocDropDownLabel             matlab.ui.control.Label
        DelivDelayEditField             matlab.ui.control.NumericEditField
        DelivDelayEditFieldLabel        matlab.ui.control.Label
        DrugDropDown                    matlab.ui.control.DropDown
        DrugDropDown_2Label             matlab.ui.control.Label
        ReceptorDropDown                matlab.ui.control.DropDown
        ReceptorDropDownLabel           matlab.ui.control.Label
        PharmacologySwitch              matlab.ui.control.Switch
        PhotometryPanel                 matlab.ui.container.Panel
        PhotWavelengthLabel             matlab.ui.control.Label
        PhotFluorophoreLabel            matlab.ui.control.Label
        LRLabel_8                       matlab.ui.control.Label
        PhotomtryLRSwitch               matlab.ui.control.Switch
        Switch4Label                    matlab.ui.control.Label
        WavelengthDropDown              matlab.ui.control.DropDown
        WavelengthDropDownLabel         matlab.ui.control.Label
        FluorophoreDropDown             matlab.ui.control.DropDown
        FluorophoreDropDownLabel        matlab.ui.control.Label
        FiberLocationLabel              matlab.ui.control.Label
        PhotFiber1LocDropDown           matlab.ui.control.DropDown
        Fiber1DropDownLabel             matlab.ui.control.Label
        PhotometrySwitch                matlab.ui.control.Switch
        SelectProjectDirectoryDropDownLabel  matlab.ui.control.Label
        NeuropixelsPanel                matlab.ui.container.Panel
        LRLabel_2                       matlab.ui.control.Label
        LRLabel                         matlab.ui.control.Label
        NpxlsLRSwitch2                  matlab.ui.control.Switch
        Switch_6Label                   matlab.ui.control.Label
        NpxlsLRSwitch1                  matlab.ui.control.Switch
        Switch_5Label                   matlab.ui.control.Label
        Npxls1LocDropDown               matlab.ui.control.DropDown
        Npxls1DropDown_3Label           matlab.ui.control.Label
        N2LocationLabel                 matlab.ui.control.Label
        N1LocationLabel                 matlab.ui.control.Label
        Npxls2LocDropDown               matlab.ui.control.DropDown
        Npxls2DropDownLabel             matlab.ui.control.Label
        Npxls_FSEditField               matlab.ui.control.NumericEditField
        RefFSLabel                      matlab.ui.control.Label
        NeuropixelsSwitch               matlab.ui.control.Switch
        ExpmntDropDownLabel             matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        slTarget=slrealtime; % Description
        isStopped % Description
        sampleTime=0.001 % Description   
        params=setBaseParams(struct);
        sessionParams=[];
        cyberGreen=[0.24,0.94,0.46];
        disableGreen=[0.05,0.14,0.08];
        cyberOrange=[1.00,0.41,0.16];
        cyberGrey=[0.18,0.18,0.22];
        gX=0; % initialize gate count
        tX=0; % initialize trial count
        dataBufferButtonMode="gate"; % initialize databufferbutton
        % logsout=[];
    end
    
    methods (Access = private)
        
        function setSimulinkParams(app)
            T_npxls = (1/app.Npxls_FSEditField.Value)/(app.sampleTime);
            T_pupil = (1/app.PupilFSEditField.Value)/(app.sampleTime);
            T_whisk = (1/app.WhiskFSEditField.Value)/(app.sampleTime);
            load_system('nCORTEx');
            mdlWks = get_param('nCORTEx','ModelWorkspace');
            assignin(mdlWks, 'tStop', (app.SessionTimerminEditField.Value*60)); % minute to second conversion
            assignin(mdlWks, 'T_npxls', T_npxls);
            assignin(mdlWks, 'T_pupil', T_pupil);
            assignin(mdlWks, 'T_whisk', T_whisk);
            assignin(mdlWks, 'toneDuration', 1.5);
            assignin(mdlWks, 'maxFrames', (app.SessionTimerminEditField.Value*60)/app.sampleTime);
            % set_param('nCORTEx','ModelWorkspace',mdlWks);
            % set_param('nCORTEx','StopTime',app.SessionTimerminEditField.Value)
            % set_param('nCORTEx'...  
            %     , 'tStop', app.SessionTimerminEditField.Value
            %     , 'T_npxls', T_npxls ...
            %     , 'T_pupil', T_pupil ...
            %     , 'T_whisk', T_whisk ...
            %     , 'toneDuration', 1.5);
        end
        
        function pyDict = struct2dict(app, matStruct)
            % Returns a matlab struct as a text formatted python dictionary
            % containing struct fields as dict keys and struct values as dict values
            % Converts a MATLAB struct to a text-formatted Python dictionary
    
            % Start with an empty string
            pyDict = '{';
            
            % Loop through the fields of the struct
            fields = fieldnames(matStruct);
            for i = 1:length(fields)
                field = fields{i};
                value = matStruct.(field);
                
                % Convert the field name and value to strings
                fieldStr = sprintf('"%s"', field);
                valueStr = '';
                
                % Check the data type of the value and format it accordingly
                if ischar(value)
                    valueStr = sprintf('"%s"', value);
                elseif isnumeric(value) || islogical(value)
                    valueStr = num2str(value);
                elseif isstruct(value)
                    % Recursively convert nested structs
                    valueStr = struct2dict(app, value);
                else
                    % Handle other data types as needed
                    error('Unsupported data type in struct.');
                end
                
                % Combine the field and value strings
                pairStr = sprintf('%s: %s', fieldStr, valueStr);
                
                % Add the pair to the dictionary string
                if i < length(fields)
                    pyDict = [pyDict, pairStr, ', '];
                else
                    pyDict = [pyDict, pairStr];
                end
            end
            
            % Close the dictionary
            pyDict = [pyDict, '}'];
            
        end
        
        function colorCodeByValid(app, labelParam, isValid)
            switch isValid
                case 1
                    app.(labelParam).FontColor = app.cyberGreen;
                case 0
                    app.(labelParam).FontColor = app.cyberOrange;
                case -1
                    app.(labelParam).FontColor = app.cyberGrey;
            end            
        end
        
        function sessionLabel = buildSessionLabel(app)
            date = string(datetime('now', 'Format', 'yyyy-MM-dd'));
            subjectID = string(app.SelectSubjectDropDown.Value);
            subjectID = strcat("_",subjectID);

            pharmaLabel = strcat(string(app.PharmaLRSwitch.Value),"-",string(app.ReceptorDropDown.Value),"-",string(app.DrugDropDown.Value),"-",string(app.DosageDropDown.Value));
            pharmaLabel = strcat("_",pharmaLabel);
            pharmaLabel = sessionLabelSwitch(app, 'PharmacologySwitch', pharmaLabel);
            

            npxlsLabel = strcat(string(app.NpxlsLRSwitch1.Value),"-",string(app.Npxls1LocDropDown.Value),"-","Npxls1","-",string(app.NpxlsLRSwitch2.Value),"-",string(app.Npxls2LocDropDown.Value),"-","Npxls2");
            npxlsLabel = strcat("_",npxlsLabel);
            npxlsLabel = sessionLabelSwitch(app, 'NeuropixelsSwitch', npxlsLabel);
            

            photomtryLabel = strcat(string(app.PhotomtryLRSwitch.Value),"-",string(app.PhotFiber1LocDropDown.Value),"-",string(app.FluorophoreDropDown.Value),"-",string(app.WavelengthDropDown.Value));
            photomtryLabel = strcat("_",photomtryLabel);
            photomtryLabel = sessionLabelSwitch(app, 'PhotometrySwitch', photomtryLabel);
            

            optoLabel = strcat(string(app.OptoLRSwitch.Value),"-",string(app.OptoFiber1LocDropDown.Value),"-",string(app.OpsinDropDown.Value),"-",string(app.OptoWavelengthDropDown.Value));
            optoLabel = strcat("_",optoLabel);
            optoLabel = sessionLabelSwitch(app, 'OptogeneticsSwitch', optoLabel);
            
            % piezoLabel =
            
            gate=strcat("_g",string(app.gX));            
            trial=strcat("_t",string(app.tX));

            sessionLabel = strcat(date,subjectID,pharmaLabel,npxlsLabel,photomtryLabel,optoLabel,gate,trial);
        end
        
        function switchedLabel = sessionLabelSwitch(app, switchRef, label)
            switch app.(switchRef).Value
                case 'On'
                    switchedLabel = label;
                case 'Off'
                    switchedLabel = '';
                otherwise
                    switchedLabel = '';
            end
        end       
        
        
        function refreshDataBufferStatus(app)
            app.DataBufferButton.Icon="brkIcon.jpg";
            app.dataBufferButtonMode="gate";
            dirEmpty = ~(length(dir(app.params.paths.tempDir))>2);
            % check if temp is not empty
            if ~dirEmpty
                % enable this button (the data buffer button) if
                % nCORTEx_temp has any trial data
                app.DataBufferButton.Enable=true;
                app.DataBufferButton.BackgroundColor=app.cyberGreen;
            else
                % if temp is empty, disable the button 
                app.DataBufferButton.Enable=false;
                app.DataBufferButton.BackgroundColor=app.disableGreen;
                DiscardButtonEnable(app,0);
            end
        end
        
        function DiscardButtonEnable(app, EN)
            switch EN
                case 0
                    app.DiscardButton.Enable=false;
                    app.DiscardButton.BackgroundColor=app.disableGreen;
                case 1
                    app.DiscardButton.Enable=true;
                    app.DiscardButton.BackgroundColor=app.cyberGreen;
            end
            
        end
        
        function resetLoggingActions(app)
            DiscardButtonEnable(app, 0);            
            refreshDataBufferStatus(app);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            refreshDataBufferStatus(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.isStopped=0;            
            app.SessionLabelLabel.Text=buildSessionLabel(app);
            % empty Temp folder
            % - code here to empty
            app.PupilLabel.FontColor=[0.18,0.18,0.22];
            app.WhiskLabel.FontColor=[0.18,0.18,0.22];

            app.params.spinParams.execStatus='start';
            app.params.spinParams.sessionLabel = app.SessionLabelLabel.Text;
            % re-update fps values based on GUI            
            % app.params.spinParams.saveDir=app.SelectProjectDirectoryDropDownLabel.Text;
            app.params.spinParams.saveDir=app.params.paths.tempDir;
            

            %% Build and Load model
            tg = app.slTarget;
            disp(strcat('Accessing', {' '},tg.TargetSettings.name,{'...'}));
            % set Sim Pameters
            setSimulinkParams(app);
            slbuild('nCORTEx');                       
            load(tg,'nCORTEx');                                  

            app.slTarget=tg;

            %% Initiate processes             
            if ~exist(app.params.paths.tempDir,'dir'), mkdir(app.params.paths.tempDir); end
            
            % Use parfeval to run the Python scripts concurrently
            app.params.spinParams.camSelect=app.params.spinParams.pupilSN;
            spinParams0 = struct2dict(app, app.params.spinParams);
            app.params.spinParams.camSelect=app.params.spinParams.whiskSN;
            spinParams1 = struct2dict(app, app.params.spinParams);
            spinParamsJSON0 = jsonencode(spinParams0);
            spinParamsJSON1 = jsonencode(spinParams1);           

            system(strjoin(["parallel './callSpinTEx.sh' ::: " spinParamsJSON0 ' ' spinParamsJSON1 ' &']));
            pause(5)

            % save session params
            app.sessionParams = app.params;
            app.sessionParams.pupilFS=app.PupilFSEditField.Value;
            app.sessionParams.whiskFS=app.WhiskFSEditField.Value;
            app.sessionParams.npxlsFS=app.Npxls_FSEditField.Value;
            app.sessionParams.sessionLabel=app.SessionLabelLabel.Text;
            % start session
            start(app.slTarget)        

            % check if ready for data buffering/streaming
            refreshDataBufferStatus(app);
            
            % Draw a progress bar            
            sessionTime = app.SessionTimerminEditField.Value*60; % minute to second conversion
            cla(app.UIAxes)     
            cla(app.UIAxes_PupilFrames)
            cla(app.UIAxes_WhiskFrames)

            while true
                % filledWidth=i;
                % log = getlogsout()
                widthPrct = (app.slTarget.ModelStatus.ExecTime/sessionTime); 
                
                numPupilFrames = countFrames(fullfile(app.params.paths.tempDir,"Raw Pupil Data",app.SessionLabelLabel.Text));
                totPupilFrames = app.sessionParams.pupilFS * sessionTime;
                pupPrct = numPupilFrames/totPupilFrames;                               
                
                numWhiskFrames = countFrames(fullfile(app.params.paths.tempDir, "Raw Whisker Data",app.SessionLabelLabel.Text));
                totWhiskFrames = app.sessionParams.whiskFS * sessionTime;
                wskPrct = numWhiskFrames/totWhiskFrames;

                app.PupilFrmCount.Text=string(numPupilFrames);
                app.WhiskFrmCount.Text=string(numWhiskFrames);
                switch(app.params.hostName)
                    case 'electro'
                        drawrectangle(app.UIAxes,'Position', [0, 0,widthPrct, 1],'InteractionsAllowed','none','Color',[0.24,0.94,0.46]);
                        if pupPrct > 0
                            drawrectangle(app.UIAxes_PupilFrames,'Position', [0, 0,1, pupPrct],'InteractionsAllowed','none','Color',[0.24,0.94,0.46]);
                        end
                        if wskPrct > 0
                            drawrectangle(app.UIAxes_WhiskFrames,'Position', [0, 0,1, wskPrct],'InteractionsAllowed','none','Color',[0.24,0.94,0.46]);
                        end
                    case 'genoma'
                        drawrectangle(app.UIAxes,'Position', [0, 0,widthPrct, 1],'InteractionsAllowed','none','Color',[1.00,0.33,0.42]);
                        if pupPrct > 0
                            drawrectangle(app.UIAxes_PupilFrames,'Position', [0, 0,1, pupPrct],'InteractionsAllowed','none','Color',[1.00,0.33,0.42]);
                        end
                        if wskPrct > 0
                            drawrectangle(app.UIAxes_WhiskFrames,'Position', [0, 0,1, wskPrct],'InteractionsAllowed','none','Color',[1.00,0.33,0.42]);
                        end
                    otherwise
                        drawrectangle(app.UIAxes,'Position', [0, 0,widthPrct, 1],'InteractionsAllowed','none','Color',[0.83,0.62,0.16]);
                end
                drawnow;
                if app.isStopped
                    % If model completes without user's stop input, stop camera acquisition            
                    app.params.spinParams.execStatus='stop';
                    spinParams = struct2dict(app, app.params.spinParams); 
                    spinParamsJSON = jsonencode(spinParams);
                    disp("stopping slx")
                    stop(app.slTarget)
                    disp(strcat('Application',{' '},'Stopped'))
                    % Release resources
                    system(strjoin(["./callSpinTEx.sh" '' spinParamsJSON ' &']))          
                    break;
                elseif string(app.slTarget.ModelStatus.State)=="DONE"
                    % If model completes without user's stop input, stop camera acquisition            
                    app.params.spinParams.execStatus='stop';
                    spinParams = struct2dict(app, app.params.spinParams); 
                    spinParamsJSON = jsonencode(spinParams);
                    disp("stopping slx")
                    stop(app.slTarget)
                    disp(strcat('Application',{' '},'Stopped'))
                    % Release resources
                    system(strjoin(["./callSpinTEx.sh" '' spinParamsJSON ' &']))          
                    % system("pkill python")
                    break;
                end                               
            end                 
            
            % final dir count
            numPupilFrames = countFrames(fullfile(app.params.paths.tempDir,"Raw Pupil Data",app.SessionLabelLabel.Text));                                                          
            numWhiskFrames = countFrames(fullfile(app.params.paths.tempDir, "Raw Whisker Data",app.SessionLabelLabel.Text));                
            app.PupilFrmCount.Text=string(numPupilFrames);
            app.WhiskFrmCount.Text=string(numWhiskFrames);
            if numPupilFrames == app.sessionParams.pupilFS*round(sessionTime)
                % make green for OK
                app.PupilLabel.FontColor=[0.24,0.94,0.46];
            else
                % make orange for WARNING
                app.PupilLabel.FontColor=[1.00,0.41,0.16];
            end
            if numWhiskFrames == app.sessionParams.whiskFS*round(sessionTime)
                app.WhiskLabel.FontColor=[0.24,0.94,0.46];
            else
                app.WhiskLabel.FontColor=[1.00,0.41,0.16];
            end

            % increment trial counter (for next trial)
            app.tX=app.tX+1;

            % store logsout in temp
            logsout = evalin('base','logsout');
            if ~exist(fullfile(app.params.paths.tempDir,"Raw Behavior Data"),'dir'); mkdir(fullfile(app.params.paths.tempDir,"Raw Behavior Data")); end
            save(fullfile(app.params.paths.tempDir,"Raw Behavior Data",strcat(app.sessionParams.sessionLabel,".mat")),"logsout");
            % store metadata
            if ~exist(fullfile(app.params.paths.tempDir,"Experiment Metadata"),'dir'); mkdir(fullfile(app.params.paths.tempDir,"Experiment Metadata")); end
            sessionParams = app.sessionParams;
            save(fullfile(app.params.paths.tempDir,"Experiment Metadata",strcat(app.sessionParams.sessionLabel,".mat")),"sessionParams"); 
                
            % check if ready for data buffering/streaming
            refreshDataBufferStatus(app);           
            

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.isStopped=1;
            % tg = app.slTarget;                        
            % stop(app.slTarget);            
            % app.slTarget=tg;
            % disp(strcat('Application',{' '},'Stopped'))
        end

        % Button pushed function: DataBufferButton
        function DataBufferButtonPushed(app, event)
          
            switch app.dataBufferButtonMode
                case "gate"                    
                    % increment the gate
                    app.gX = app.gX + 1;
                    app.tX = 0; % reset trial counter

                    % enable discard button
                    DiscardButtonEnable(app, 1);                    
                    
                    % switch to upload button state
                    app.dataBufferButtonMode="upload";                    
                    app.DataBufferButton.Icon="upload.png";                    
                case "upload"                    
                    % upload data buffer to project folder and clear
                    streamAcquisition(app);
                    % rmdir(app.params.paths.tempDir);
                    
                    % reset button to gate mode for next acquisition
                    app.dataBufferbuttonMode="gate";
                    app.DataBufferButton.Icon="brkIcon.jpg";
                    refreshDataBufferStatus(app); % reinitialize after emptying temp

                    % reset logging
                    resetLoggingActions(app);
            end
            
            % savePath = app.SelectProjectDirectoryDropDownLabel.Text;
            % disp(['saving log data to: ',savePath]);
            % logData = evalin('base','logsout');
            % save(fullfile(app.SelectProjectDirectoryDropDownLabel.Text,"log.mat"),'logData')
        end

        % Value changed function: VisionSwitch
        function VisionSwitchValueChanged(app, event)
            value = app.VisionSwitch.Value;
            switch value
                case 'On'
                    app.VisionPanel.ForegroundColor=[1,1,1];
                case 'Off'
                    app.VisionPanel.ForegroundColor=[0.36,0.36,0.36];
            end            
            
        end

        % Value changed function: NeuropixelsSwitch
        function NeuropixelsSwitchValueChanged(app, event)
            value = app.NeuropixelsSwitch.Value;
            switch value
                case 'On'
                    app.NeuropixelsPanel.ForegroundColor=[1,1,1];
                case 'Off'
                    app.NeuropixelsPanel.ForegroundColor=[0.36,0.36,0.36];
            end
            
            LabelButtonPushed(app);
        end

        % Value changed function: PhotometrySwitch
        function PhotometrySwitchValueChanged(app, event)
            value = app.PhotometrySwitch.Value;
            switch value
                case 'On'
                    app.PhotometryPanel.ForegroundColor=[1,1,1];
                case 'Off'                    
                    app.PhotometryPanel.ForegroundColor=[0.36,0.36,0.36];
            end

            LabelButtonPushed(app);            
        end

        % Value changed function: connectTarget
        function connectTargetValueChanged(app, event)
            value = app.connectTarget.Value;
            switch value
                case 1
                    tg = app.slTarget;
                    disp('connecting...')
                    tg.connect;                    
                    disp(strcat(tg.TargetSettings.name,{' '},'connected'))
                    app.connectTarget.Icon='slrtConnectIcon.png';
                    app.slTarget=tg;
                case 0
                    tg = app.slTarget;
                    disp('disconnecting...')
                    tg.disconnect;                    
                    disp(strcat(tg.TargetSettings.name,{' '},'disconnected'))
                    app.connectTarget.Icon='slrtDisconnectIcon.png';
                    app.slTarget=tg;
            end
        end

        % Value changed function: SelectProjectDirectoryDropDown
        function SelectProjectDirectoryDropDownValueChanged(app, event)
            value = app.SelectProjectDirectoryDropDown.Value;            
            switch value
                case 'Neuromodulation for Pain'
                    app.SelectProjectDirectoryDropDownLabel.Text = fullfile(app.params.paths.NECdrive_root,'Project_Neuromodulation-for-Pain','Raw Behavior Data');
                case 'Neurotransmitter Exploration'
                    app.SelectProjectDirectoryDropDownLabel.Text = fullfile(app.params.paths.NECdrive_root,'Project_Neurotransmitter-Exploration','Raw Behavior Data');
                case 'Thalamic-Pupil Synch'
                    app.SelectProjectDirectoryDropDownLabel.Text = fullfile(app.params.paths.NECdrive_root,'Project_Thalamic-Pupil-Synchronization','Raw Behavior Data');
                case 'SSD'
                    % app.SelectProjectDirectoryDropDownLabel.Text = fullfile(params.paths.NECdrive_root,'Project_Neurotransmitter-Exploration','Raw Behavior Data');;
                    app.SelectProjectDirectoryDropDownLabel.Text = 'SSD missing mountpoint';
                    disp('warning: SSD missing mountpoint')
                case 'Browse'
                    path = uigetdir;
                    app.SelectProjectDirectoryDropDownLabel.Text = path;
            end
        end

        % Value changed function: Switch2
        function Switch2ValueChanged(app, event)
            value = app.Switch2.Value;
            switch value
                case 'Outputs'
                    app.VisionPanel.Visible="on";
                    app.NeuropixelsPanel.Visible="on";
                    app.PhotometryPanel.Visible="on";

                    app.OptogeneticsPanel.Visible="off";
                    app.PiezoPanel.Visible="off";
                    app.PharmacologyPanel.Visible="off";
                    
                case 'Inputs'
                    app.VisionPanel.Visible="off";
                    app.NeuropixelsPanel.Visible="off";
                    app.PhotometryPanel.Visible="off";

                    app.OptogeneticsPanel.Visible="on";
                    app.PiezoPanel.Visible="on";
                    app.PharmacologyPanel.Visible="on";
            end
        end

        % Button pushed function: ValidateButton
        function ValidateButtonPushed(app, event)
            % load slrealtime log data
            logData = evalin('base','logsout');
            
            % Retrieve signal traces
            PulseGen1Hz = find(logData,'PulseGen1Hz');
            whiskCam_trig = find(logData,'whiskCam_trig');
            pupilCam_trig = find(logData, 'pupilCam_trig');
            npxls_trig = find(logData, 'npxls_trig');

            % compare timing to 1Hz reference
            cycleTimes = locateCycles(app.sessionParams,PulseGen1Hz.Values.Data);
            % whisker trig stats
            numPupilEdges = countEdges(app.sessionParams, pupilCam_trig.Values.Data, []);
            numPupEdgesByCycle = countEdges(app.sessionParams,pupilCam_trig.Values.Data,cycleTimes);
            meanPupilPerSec = mean(numPupEdgesByCycle);
            stdPupilPerSec = std(numPupEdgesByCycle);
            % pupil trig stats
            numWhiskEdges = countEdges(app.sessionParams, whiskCam_trig.Values.Data, []);
            numWhiskEdgesByCycle = countEdges(app.sessionParams,whiskCam_trig.Values.Data,cycleTimes);
            meanWhiskPerSec = mean(numWhiskEdgesByCycle);
            stdWhiskPerSec = std(numWhiskEdgesByCycle);
            % npxls ref trig stats
            numNpxlsEdges = countEdges(app.sessionParams, npxls_trig.Values.Data, []);
            numNpxlsEdgesByCycle = countEdges(app.sessionParams,npxls_trig.Values.Data,cycleTimes);
            meanNpxlsPerSec = mean(numNpxlsEdgesByCycle);
            stdNpxlsPerSec = std(numNpxlsEdgesByCycle);

            % validate pupil and whisker frame counts
            numPupilFrames = countFrames(fullfile(app.sessionParams.paths.tempDir,"Raw Pupil Data", app.SessionLabelLabel.Text));
            numWhiskFrames = countFrames(fullfile(app.sessionParams.paths.tempDir,"Raw Whisker Data", app.SessionLabelLabel.Text));
            
            % assert validation logic
            pupilFramesOk = (numPupilFrames == numPupilEdges);
            whiskFramesOk = (numWhiskFrames == numWhiskEdges);
            % Pupil valid
            pupilMeanOk = (abs(app.sessionParams.pupilFS - meanPupilPerSec) < app.sessionParams.validationParams.meanThreshold);
            pupilStdOk = (stdPupilPerSec < app.sessionParams.validationParams.stdThreshold);
            pupilStatOk = (pupilMeanOk & pupilStdOk);
            % whisk valid
            whiskMeanOk = (abs(app.sessionParams.whiskFS - meanWhiskPerSec) < app.sessionParams.validationParams.meanThreshold);
            whiskStdOk = (stdWhiskPerSec < app.sessionParams.validationParams.stdThreshold);
            whiskStatOk = (whiskMeanOk & whiskStdOk);
            % Npxls valid
            npxlsMeanOk = (abs(app.sessionParams.npxlsFS - meanNpxlsPerSec) < app.sessionParams.validationParams.meanThreshold);
            npxlsStdOk = (stdNpxlsPerSec < app.sessionParams.validationParams.stdThreshold);
            npxlsStatOk = (npxlsMeanOk & npxlsStdOk);

            sessionOk = (pupilFramesOk & whiskFramesOk & pupilStatOk & whiskStatOk & npxlsStatOk);

            % disp stats on app
            
            % fun visual
            for i=1:50
                rand1 = randi([1,300]);
                rand2 = randi([1,500]);
                rand3 = randi([1,200]);
                
                app.PupilMeanHz.Text=string(rand1);
                app.PupilStdHz.Text=string(rand3);

                app.WhiskMeanHz.Text=string(rand2);
                app.WhiskStdHz.Text=string(rand1);

                app.NpxlsMeanHz.Text=string(rand3);
                app.NpxlsStdHz.Text=string(rand2);
                pause(0.01)
            end

            % report final values
            app.PupilMeanHz.Text=string(meanPupilPerSec);
            app.PupilStdHz.Text=string(stdPupilPerSec);

            app.WhiskMeanHz.Text=string(meanWhiskPerSec);
            app.WhiskStdHz.Text=string(stdWhiskPerSec);

            app.NpxlsMeanHz.Text=string(meanNpxlsPerSec);
            app.NpxlsStdHz.Text=string(stdNpxlsPerSec); 

            % color indicator for validity
            colorCodeByValid(app,'PupilMeanHz',pupilMeanOk);
            colorCodeByValid(app,'PupilStdHz',pupilStdOk);
            colorCodeByValid(app,'PupilStatLabel',pupilStatOk);

            colorCodeByValid(app,'WhiskMeanHz',whiskMeanOk);
            colorCodeByValid(app,'WhiskStdHz',whiskStdOk);
            colorCodeByValid(app,'WhiskStatLabel',whiskStatOk);
            
            colorCodeByValid(app,'NpxlsMeanHz',npxlsMeanOk);
            colorCodeByValid(app,'NpxlsStdHz',npxlsStdOk);
            colorCodeByValid(app,'NpxlsStatLabel',npxlsStatOk);            


        end

        % Callback function: DosageDropDown, DrugDropDown, 
        % ...and 17 other components
        function LabelButtonPushed(app, event)
            sessionLabel = buildSessionLabel(app);
            app.SessionLabelLabel.Text=sessionLabel;
        end

        % Value changed function: PharmacologySwitch
        function PharmacologySwitchValueChanged(app, event)
            value = app.PharmacologySwitch.Value;
            switch value
                case 'On'
                    app.PharmacologyPanel.ForegroundColor=[1,1,1];
                case 'Off'
                    app.PharmacologyPanel.ForegroundColor=[0.36,0.36,0.36];
            end

            LabelButtonPushed(app);            
        end

        % Value changed function: OptogeneticsSwitch
        function OptogeneticsSwitchValueChanged(app, event)
            value = app.OptogeneticsSwitch.Value;
            
            LabelButtonPushed(app);
        end

        % Value changed function: PiezoSwitch
        function PiezoSwitchValueChanged(app, event)
            value = app.PiezoSwitch.Value;
            
            LabelButtonPushed(app);
        end

        % Button pushed function: DiscardButton
        function DiscardButtonPushed(app, event)
            % clear tempdir
            rmdir(fullfile(app.params.paths.tempDir));
            % disable discard button
            resetLoggingActions(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1 0.11 0.12 0.13 0.14 0.15 0.16 0.17 0.18 0.19 0.2 0.21 0.22 0.23 0.24 0.25 0.26 0.27 0.28 0.29 0.3 0.31 0.32 0.33 0.34 0.35 0.36 0.37 0.38 0.39 0.4 0.41 0.42 0.43 0.44 0.45 0.46 0.47 0.48 0.49 0.5 0.51 0.52 0.53 0.54 0.55 0.56 0.57 0.58 0.59 0.6 0.61 0.62 0.63 0.64 0.65 0.66 0.67 0.68 0.69 0.7 0.71 0.72 0.73 0.74 0.75 0.76 0.77 0.78 0.79 0.8 0.81 0.82 0.83 0.84 0.85 0.86 0.87 0.88 0.89 0.9 0.91 0.92 0.93 0.94 0.95 0.96 0.97 0.98 0.99 1];
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.ExpmntDropDownLabel = uilabel(app.UIFigure);
            app.ExpmntDropDownLabel.Text = 'Expmnt.';
            app.NeuropixelsPanel = uipanel(app.UIFigure);
            app.NeuropixelsPanel.Title = 'Neuropixels';
            app.NeuropixelsSwitch = uiswitch(app.NeuropixelsPanel, 'slider');
            app.NeuropixelsSwitch.ValueChangedFcn = createCallbackFcn(app, @NeuropixelsSwitchValueChanged, true);
            app.NeuropixelsSwitch.Value = 'On';
            app.RefFSLabel = uilabel(app.NeuropixelsPanel);
            app.RefFSLabel.Text = 'Ref FS';
            app.Npxls_FSEditField = uieditfield(app.NeuropixelsPanel, 'numeric');
            app.Npxls_FSEditField.Value = 250;
            app.Npxls2DropDownLabel = uilabel(app.NeuropixelsPanel);
            app.Npxls2DropDownLabel.Text = 'Npxls 2';
            app.Npxls2LocDropDown = uidropdown(app.NeuropixelsPanel);
            app.Npxls2LocDropDown.Items = {'VPM', 'S1', '+'};
            app.Npxls2LocDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.Npxls2LocDropDown.Value = 'VPM';
            app.N1LocationLabel = uilabel(app.NeuropixelsPanel);
            app.N1LocationLabel.Text = 'N1 Location';
            app.N2LocationLabel = uilabel(app.NeuropixelsPanel);
            app.N2LocationLabel.Text = 'N2 Location';
            app.Npxls1DropDown_3Label = uilabel(app.NeuropixelsPanel);
            app.Npxls1DropDown_3Label.Text = 'Npxls 1';
            app.Npxls1LocDropDown = uidropdown(app.NeuropixelsPanel);
            app.Npxls1LocDropDown.Items = {'VPM', 'S1', '+'};
            app.Npxls1LocDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.Npxls1LocDropDown.Value = 'VPM';
            app.Switch_5Label = uilabel(app.NeuropixelsPanel);
            app.Switch_5Label.Text = 'Switch';
            app.NpxlsLRSwitch1 = uiswitch(app.NeuropixelsPanel, 'slider');
            app.NpxlsLRSwitch1.Items = {'L', 'R'};
            app.NpxlsLRSwitch1.ValueChangedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.NpxlsLRSwitch1.Value = 'L';
            app.Switch_6Label = uilabel(app.NeuropixelsPanel);
            app.Switch_6Label.Text = 'Switch';
            app.NpxlsLRSwitch2 = uiswitch(app.NeuropixelsPanel, 'slider');
            app.NpxlsLRSwitch2.Items = {'L', 'R'};
            app.NpxlsLRSwitch2.ValueChangedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.NpxlsLRSwitch2.Value = 'L';
            app.LRLabel = uilabel(app.NeuropixelsPanel);
            app.LRLabel.Text = 'L   /   R';
            app.LRLabel_2 = uilabel(app.NeuropixelsPanel);
            app.LRLabel_2.Text = 'L   /   R';
            app.SelectProjectDirectoryDropDownLabel = uilabel(app.UIFigure);
            app.SelectProjectDirectoryDropDownLabel.Text = 'Select Project Directory';
            app.PhotometryPanel = uipanel(app.UIFigure);
            app.PhotometryPanel.Title = 'Photometry';
            app.PhotometrySwitch = uiswitch(app.PhotometryPanel, 'slider');
            app.PhotometrySwitch.ValueChangedFcn = createCallbackFcn(app, @PhotometrySwitchValueChanged, true);
            app.PhotometrySwitch.Value = 'On';
            app.Fiber1DropDownLabel = uilabel(app.PhotometryPanel);
            app.Fiber1DropDownLabel.Text = 'Fiber 1';
            app.PhotFiber1LocDropDown = uidropdown(app.PhotometryPanel);
            app.PhotFiber1LocDropDown.Items = {'VPM', 'S1', '+'};
            app.PhotFiber1LocDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.PhotFiber1LocDropDown.Value = 'VPM';
            app.FiberLocationLabel = uilabel(app.PhotometryPanel);
            app.FiberLocationLabel.Text = 'Fiber Location:';
            app.FluorophoreDropDownLabel = uilabel(app.PhotometryPanel);
            app.FluorophoreDropDownLabel.Text = 'Fluorophore';
            app.FluorophoreDropDown = uidropdown(app.PhotometryPanel);
            app.FluorophoreDropDown.Items = {'EGFP', '+'};
            app.FluorophoreDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.FluorophoreDropDown.Value = 'EGFP';
            app.WavelengthDropDownLabel = uilabel(app.PhotometryPanel);
            app.WavelengthDropDownLabel.Text = 'Wavelength';
            app.WavelengthDropDown = uidropdown(app.PhotometryPanel);
            app.WavelengthDropDown.Items = {'640', '+'};
            app.WavelengthDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.WavelengthDropDown.Value = '640';
            app.Switch4Label = uilabel(app.PhotometryPanel);
            app.Switch4Label.Text = 'Switch4';
            app.PhotomtryLRSwitch = uiswitch(app.PhotometryPanel, 'slider');
            app.PhotomtryLRSwitch.Items = {'L', 'R'};
            app.PhotomtryLRSwitch.ValueChangedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.PhotomtryLRSwitch.Value = 'L';
            app.LRLabel_8 = uilabel(app.PhotometryPanel);
            app.LRLabel_8.Text = 'L   /   R';
            app.PhotFluorophoreLabel = uilabel(app.PhotometryPanel);
            app.PhotFluorophoreLabel.Text = 'Fluorophore';
            app.PhotWavelengthLabel = uilabel(app.PhotometryPanel);
            app.PhotWavelengthLabel.Text = 'Wavelength (nm)';
            app.PharmacologyPanel = uipanel(app.UIFigure);
            app.PharmacologyPanel.Title = 'Pharmacology';
            app.PharmacologyPanel.Visible = 'off';
            app.PharmacologySwitch = uiswitch(app.PharmacologyPanel, 'slider');
            app.PharmacologySwitch.ValueChangedFcn = createCallbackFcn(app, @PharmacologySwitchValueChanged, true);
            app.PharmacologySwitch.Value = 'On';
            app.ReceptorDropDownLabel = uilabel(app.PharmacologyPanel);
            app.ReceptorDropDownLabel.Text = 'Receptor';
            app.ReceptorDropDown = uidropdown(app.PharmacologyPanel);
            app.ReceptorDropDown.Items = {'Gq-DREADD', '+'};
            app.ReceptorDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.ReceptorDropDown.Value = 'Gq-DREADD';
            app.DrugDropDown_2Label = uilabel(app.PharmacologyPanel);
            app.DrugDropDown_2Label.Text = 'Drug';
            app.DrugDropDown = uidropdown(app.PharmacologyPanel);
            app.DrugDropDown.Items = {'CNO', '+'};
            app.DrugDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.DrugDropDown.Value = 'CNO';
            app.DelivDelayEditFieldLabel = uilabel(app.PharmacologyPanel);
            app.DelivDelayEditFieldLabel.Text = 'Deliv. Delay';
            app.DelivDelayEditField = uieditfield(app.PharmacologyPanel, 'numeric');
            app.InjLocDropDownLabel = uilabel(app.PharmacologyPanel);
            app.InjLocDropDownLabel.Text = 'Inj. Loc.';
            app.InjLocDropDown = uidropdown(app.PharmacologyPanel);
            app.InjLocDropDown.Items = {'VPM', 'S1', '+'};
            app.InjLocDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.InjLocDropDown.Value = 'VPM';
            app.InjectionLocationLabel = uilabel(app.PharmacologyPanel);
            app.InjectionLocationLabel.Text = 'Injection Location:';
            app.DosageDropDown_2Label = uilabel(app.PharmacologyPanel);
            app.DosageDropDown_2Label.Text = 'Dosage';
            app.DosageDropDown = uidropdown(app.PharmacologyPanel);
            app.DosageDropDown.Items = {'1', '+'};
            app.DosageDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.DosageDropDown.Value = '1';
            app.PharmaLRSwitch = uiswitch(app.PharmacologyPanel, 'slider');
            app.PharmaLRSwitch.Items = {'L', 'R'};
            app.PharmaLRSwitch.ValueChangedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.PharmaLRSwitch.Value = 'L';
            app.ReceptorLabel = uilabel(app.PharmacologyPanel);
            app.ReceptorLabel.Text = 'Receptor:';
            app.DrugLabel = uilabel(app.PharmacologyPanel);
            app.DrugLabel.Text = 'Drug:';
            app.DosagemLLabel = uilabel(app.PharmacologyPanel);
            app.DosagemLLabel.Text = 'Dosage (mL):';
            app.PharmaLRLabel = uilabel(app.PharmacologyPanel);
            app.PharmaLRLabel.Text = 'L   /   R';
            app.VisionPanel = uipanel(app.UIFigure);
            app.VisionPanel.Title = 'Vision';
            app.PupilSNLabel_2 = uilabel(app.VisionPanel);
            app.PupilSNLabel_2.Text = 'Pupil S/N';
            app.PupilSNDropDown = uidropdown(app.VisionPanel);
            app.PupilSNDropDown.Items = {'21277600', '+'};
            app.PupilSNDropDown.Value = '21277600';
            app.VisionSwitch = uiswitch(app.VisionPanel, 'slider');
            app.VisionSwitch.ValueChangedFcn = createCallbackFcn(app, @VisionSwitchValueChanged, true);
            app.VisionSwitch.Value = 'On';
            app.PupilFSEditField_2Label = uilabel(app.VisionPanel);
            app.PupilFSEditField_2Label.Text = 'Pupil FS';
            app.PupilFSEditField = uieditfield(app.VisionPanel, 'numeric');
            app.PupilFSEditField.Value = 10;
            app.WhiskerSNLabel_2 = uilabel(app.VisionPanel);
            app.WhiskerSNLabel_2.Text = 'Whisker S/N';
            app.WhiskerSNDropDown = uidropdown(app.VisionPanel);
            app.WhiskerSNDropDown.Items = {'23248866', '+'};
            app.WhiskerSNDropDown.Value = '23248866';
            app.WhiskFSEditFieldLabel = uilabel(app.VisionPanel);
            app.WhiskFSEditFieldLabel.Text = 'Whisk FS';
            app.WhiskFSEditField = uieditfield(app.VisionPanel, 'numeric');
            app.WhiskFSEditField.Value = 50;
            app.PupilSNLabel = uilabel(app.VisionPanel);
            app.PupilSNLabel.Text = 'Pupil S/N:';
            app.WhiskerSNLabel = uilabel(app.VisionPanel);
            app.WhiskerSNLabel.Text = 'Whisker S/N:';
            app.SpinviewButton = uibutton(app.VisionPanel, 'push');
            app.SpinviewButton.Text = 'Spinview';
            app.SessionTimerminEditFieldLabel = uilabel(app.UIFigure);
            app.SessionTimerminEditFieldLabel.Text = 'Session Timer (min )';
            app.SelectSubjectDropDownLabel = uilabel(app.UIFigure);
            app.SelectSubjectDropDownLabel.Text = 'Select Subject';
            app.SessionMetricsPanel = uipanel(app.UIFigure);
            app.SessionMetricsPanel.Title = 'Session Metrics';
            app.UIAxes_PupilFrames = uiaxes(app.SessionMetricsPanel);
            title(app.UIAxes_PupilFrames, 'Title')
            xlabel(app.UIAxes_PupilFrames, 'X')
            ylabel(app.UIAxes_PupilFrames, 'Y')
            zlabel(app.UIAxes_PupilFrames, 'Z')
            app.UIAxes_PupilFrames.XTick = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1 0.11 0.12 0.13 0.14 0.15 0.16 0.17 0.18 0.19 0.2 0.21 0.22 0.23 0.24 0.25 0.26 0.27 0.28 0.29 0.3 0.31 0.32 0.33 0.34 0.35 0.36 0.37 0.38 0.39 0.4 0.41 0.42 0.43 0.44 0.45 0.46 0.47 0.48 0.49 0.5 0.51 0.52 0.53 0.54 0.55 0.56 0.57 0.58 0.59 0.6 0.61 0.62 0.63 0.64 0.65 0.66 0.67 0.68 0.69 0.7 0.71 0.72 0.73 0.74 0.75 0.76 0.77 0.78 0.79 0.8 0.81 0.82 0.83 0.84 0.85 0.86 0.87 0.88 0.89 0.9 0.91 0.92 0.93 0.94 0.95 0.96 0.97 0.98 0.99 1];
            app.UIAxes_PupilFrames.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_WhiskFrames = uiaxes(app.SessionMetricsPanel);
            title(app.UIAxes_WhiskFrames, 'Title')
            xlabel(app.UIAxes_WhiskFrames, 'X')
            ylabel(app.UIAxes_WhiskFrames, 'Y')
            zlabel(app.UIAxes_WhiskFrames, 'Z')
            app.UIAxes_WhiskFrames.XTick = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1 0.11 0.12 0.13 0.14 0.15 0.16 0.17 0.18 0.19 0.2 0.21 0.22 0.23 0.24 0.25 0.26 0.27 0.28 0.29 0.3 0.31 0.32 0.33 0.34 0.35 0.36 0.37 0.38 0.39 0.4 0.41 0.42 0.43 0.44 0.45 0.46 0.47 0.48 0.49 0.5 0.51 0.52 0.53 0.54 0.55 0.56 0.57 0.58 0.59 0.6 0.61 0.62 0.63 0.64 0.65 0.66 0.67 0.68 0.69 0.7 0.71 0.72 0.73 0.74 0.75 0.76 0.77 0.78 0.79 0.8 0.81 0.82 0.83 0.84 0.85 0.86 0.87 0.88 0.89 0.9 0.91 0.92 0.93 0.94 0.95 0.96 0.97 0.98 0.99 1];
            app.UIAxes_WhiskFrames.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.PupilLabel = uilabel(app.SessionMetricsPanel);
            app.PupilLabel.Text = 'Pupil';
            app.WhiskLabel = uilabel(app.SessionMetricsPanel);
            app.WhiskLabel.Text = 'Whisker';
            app.WhiskFrmCount = uilabel(app.SessionMetricsPanel);
            app.WhiskFrmCount.Text = '- -';
            app.PupilFrmCount = uilabel(app.SessionMetricsPanel);
            app.PupilFrmCount.Text = '- -';
            app.ValidateButton = uibutton(app.SessionMetricsPanel, 'push');
            app.ValidateButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateButtonPushed, true);
            app.ValidateButton.Text = 'Validate';
            app.HitRate = uilabel(app.SessionMetricsPanel);
            app.HitRate.Text = '-- %';
            app.HitRateLabel = uilabel(app.SessionMetricsPanel);
            app.HitRateLabel.Text = 'Hit Rate';
            app.PupilMeanHz = uilabel(app.SessionMetricsPanel);
            app.PupilMeanHz.Text = '--';
            app.WhiskMeanHz = uilabel(app.SessionMetricsPanel);
            app.WhiskMeanHz.Text = '--';
            app.NpxlsMeanHz = uilabel(app.SessionMetricsPanel);
            app.NpxlsMeanHz.Text = '--';
            app.PupilStdHz = uilabel(app.SessionMetricsPanel);
            app.PupilStdHz.Text = '--';
            app.WhiskStdHz = uilabel(app.SessionMetricsPanel);
            app.WhiskStdHz.Text = '--';
            app.NpxlsStdHz = uilabel(app.SessionMetricsPanel);
            app.NpxlsStdHz.Text = '--';
            app.PupilStatLabel = uilabel(app.SessionMetricsPanel);
            app.PupilStatLabel.Text = 'Pupil:';
            app.WhiskStatLabel = uilabel(app.SessionMetricsPanel);
            app.WhiskStatLabel.Text = 'Whisker:';
            app.NpxlsStatLabel = uilabel(app.SessionMetricsPanel);
            app.NpxlsStatLabel.Text = 'Npxls:';
            app.MeanHzLabel = uilabel(app.SessionMetricsPanel);
            app.MeanHzLabel.Text = 'Mean (Hz)';
            app.StdHzLabel = uilabel(app.SessionMetricsPanel);
            app.StdHzLabel.Text = 'Std (Hz)';
            app.OptogeneticsPanel = uipanel(app.UIFigure);
            app.OptogeneticsPanel.Title = 'Optogenetics';
            app.OptogeneticsPanel.Visible = 'off';
            app.OptogeneticsSwitch = uiswitch(app.OptogeneticsPanel, 'slider');
            app.OptogeneticsSwitch.ValueChangedFcn = createCallbackFcn(app, @OptogeneticsSwitchValueChanged, true);
            app.OptogeneticsSwitch.Value = 'On';
            app.Fiber10Label = uilabel(app.OptogeneticsPanel);
            app.Fiber10Label.Text = 'Fiber 1';
            app.OptoFiber1LocDropDown = uidropdown(app.OptogeneticsPanel);
            app.OptoFiber1LocDropDown.Items = {'VPM', 'S1', '+'};
            app.OptoFiber1LocDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.OptoFiber1LocDropDown.Value = 'VPM';
            app.FiberLocationLabel_2 = uilabel(app.OptogeneticsPanel);
            app.FiberLocationLabel_2.Text = 'Fiber Location';
            app.OpsinDropDownLabel = uilabel(app.OptogeneticsPanel);
            app.OpsinDropDownLabel.Text = 'Opsin';
            app.OpsinDropDown = uidropdown(app.OptogeneticsPanel);
            app.OpsinDropDown.Items = {'channelrhodopsin', '+'};
            app.OpsinDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.OpsinDropDown.Value = 'channelrhodopsin';
            app.StimPatternDropDownLabel = uilabel(app.OptogeneticsPanel);
            app.StimPatternDropDownLabel.Text = 'Stim Pattern';
            app.OptoStimPatternDropDown = uidropdown(app.OptogeneticsPanel);
            app.OptoStimPatternDropDown.Items = {'burst40Hz', '+'};
            app.OptoStimPatternDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.OptoStimPatternDropDown.Value = 'burst40Hz';
            app.OpsinLabel = uilabel(app.OptogeneticsPanel);
            app.OpsinLabel.Text = 'Opsin:';
            app.StimPatternLabel_2 = uilabel(app.OptogeneticsPanel);
            app.StimPatternLabel_2.Text = 'Stim Pattern';
            app.WavelengthnmLabel = uilabel(app.OptogeneticsPanel);
            app.WavelengthnmLabel.Text = 'Wavelength (nm)';
            app.WavelengthDropDown_6Label = uilabel(app.OptogeneticsPanel);
            app.WavelengthDropDown_6Label.Text = 'Wavelength';
            app.OptoWavelengthDropDown = uidropdown(app.OptogeneticsPanel);
            app.OptoWavelengthDropDown.Items = {'640', '+'};
            app.OptoWavelengthDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.OptoWavelengthDropDown.Value = '640';
            app.OptoLRSwitch = uiswitch(app.OptogeneticsPanel, 'slider');
            app.OptoLRSwitch.Items = {'L', 'R'};
            app.OptoLRSwitch.ValueChangedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.OptoLRSwitch.Value = 'L';
            app.LRLabel_7 = uilabel(app.OptogeneticsPanel);
            app.LRLabel_7.Text = 'L   /   R';
            app.Switch2Label = uilabel(app.UIFigure);
            app.Switch2Label.Text = 'Switch2';
            app.PiezoPanel = uipanel(app.UIFigure);
            app.PiezoPanel.Title = 'Piezo';
            app.PiezoPanel.Visible = 'off';
            app.PiezoSwitch = uiswitch(app.PiezoPanel, 'slider');
            app.PiezoSwitch.ValueChangedFcn = createCallbackFcn(app, @PiezoSwitchValueChanged, true);
            app.PiezoSwitch.Value = 'On';
            app.DataStreamingModalitiesLabel = uilabel(app.UIFigure);
            app.DataStreamingModalitiesLabel.Text = 'Data Streaming / Modalities';
            app.DataBufferButton = uibutton(app.UIFigure, 'push');
            app.DataBufferButton.ButtonPushedFcn = createCallbackFcn(app, @DataBufferButtonPushed, true);
            app.DataBufferButton.Icon = 'brkIcon.jpg';
            app.DataBufferButton.Text = '';
            app.SelectProjectDirectoryDropDown = uidropdown(app.UIFigure);
            app.SelectProjectDirectoryDropDown.Items = {'Neuromodulation for Pain', 'Neurotransmitter Exploration', 'Thalamic-Pupil Synch', 'SSD', '+', 'Browse'};
            app.SelectProjectDirectoryDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectProjectDirectoryDropDownValueChanged, true);
            app.SelectProjectDirectoryDropDown.Value = 'Neuromodulation for Pain';
            app.LoggingLabel = uilabel(app.UIFigure);
            app.LoggingLabel.Text = 'Logging';
            app.connectTarget = uibutton(app.UIFigure, 'state');
            app.connectTarget.ValueChangedFcn = createCallbackFcn(app, @connectTargetValueChanged, true);
            app.connectTarget.Icon = 'slrtDisconnectIcon.png';
            app.connectTarget.Text = 'Button';
            app.SessionTimerminEditField = uieditfield(app.UIFigure, 'numeric');
            app.SessionTimerminEditField.Value = 30;
            app.SelectSubjectDropDown = uidropdown(app.UIFigure);
            app.SelectSubjectDropDown.Items = {'3735', '3257', '+'};
            app.SelectSubjectDropDown.Value = '3735';
            app.StatusBar = slrealtime.ui.control.StatusBar(app.UIFigure);
            app.Switch2 = uiswitch(app.UIFigure, 'slider');
            app.Switch2.Items = {'Outputs', 'Inputs'};
            app.Switch2.ValueChangedFcn = createCallbackFcn(app, @Switch2ValueChanged, true);
            app.Switch2.Value = 'Outputs';
            app.ExpmntDropDown = uidropdown(app.UIFigure);
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = 'slrtStopIcon.png';
            app.StopButton.Text = '';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Icon = 'slrtRunIcon.png';
            app.StartButton.Text = '';
            app.LabelButton = uibutton(app.UIFigure, 'push');
            app.LabelButton.ButtonPushedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.LabelButton.Text = 'Label';
            app.SessionLabelLabel = uilabel(app.UIFigure);
            app.SessionLabelLabel.Text = '--';
            app.DiscardButton = uibutton(app.UIFigure, 'push');
            app.DiscardButton.ButtonPushedFcn = createCallbackFcn(app, @DiscardButtonPushed, true);
            app.DiscardButton.Icon = fullfile(pathToMLAPP, 'Icons', 'x-icon-white.png');
            app.DiscardButton.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nCORTEx_console

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end